classdef app_true < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CalculationButton       matlab.ui.control.Button
        xianshi                 matlab.ui.control.EditField
        withwithouttargetLabel  matlab.ui.control.Label
        Lamp_2                  matlab.ui.control.Lamp
        ResetButton             matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
        
        end

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)

            [filename, filepath] = uigetfile('*.xlsx', 'Select the Excel file to be imported');
            if ~isequal(filename, 0)
                data = xlsread(fullfile(filepath, filename));
                x= data(:,1);
                y= data(:,2);
                plot(app.UIAxes,x,y)
                
                % 最大值提取
                for i=1:length(y)
                    if y(i)>0.13
                        app.xianshi.Value='Target';
                        app.Lamp_2.Color='red'; 
                        break
                    end
                    
                    %% Extract the peak waveform
                    threshold = 0.025;  % Threshold
                    min_peak_distance = 0.05; % Minimum peak distance, to avoid duplicate detection of closely spaced peaks
                    
                    [pks, locs] = findpeaks(y, 'MinPeakHeight', threshold, 'MinPeakDistance', min_peak_distance);
                    num_peaks = numel(peaks);% Determine whether a peak exists
                    if num_peaks > 0
                        if num_peaks==2
                            window_size = 15;
                            locs1 = locs(1);     % Sampling point value of the first wave crest
                            locs2 = locs(2);     % Sampling point value of the second wave crest
                            pks1 = pks(1);       % Amplitude of the first wave crest
                            pks2 = pks(2);       % Amplitude of the second wave crest
                            pks11 = pks1 / 2;    % Half amplitude value
                            pks22 = pks2 / 2;    % Half amplitude value
                            NNN1_new = NNN(locs1 - window_size : locs1);   % Due to overlap between the two waves, take the first 15+1 points for the first wave and the last 15+1 points for the second wave
                            NNN2_new = NNN(locs2 : locs2 + window_size);   % Typically the target
                            tt3 = (1 : window_size + 1);                   % Sequence from 1 to window_size+1 (inclusive)


                             for i=1:window_size+1      %Find the points corresponding to the half-amplitude values.
                                 if NNN1_new(i)>pks11;
                                     bb1=i;
                                     break
                                 end
                             end
                            length1=2*(window_size+1-bb1);
                             for i=1:window_size+1      %Find the points corresponding to the half-amplitude values.
                                 if NNN2_new(i)<pks22;
                                     bb2=i;
                                     break
                                 end
                             end
                             length2=2*bb2;
                            if length2<length1
                                if pks(1)<0.8*10^-5
                                    if pks(2)>1.3*10^-5
                                       app.xianshi.Value='Target';
                                       app.Lamp.Color='red';
                                    end
                                end
                            end   
                        end
                        if num_peaks==1
                            if pks(1)>0.1
                               app.xianshi.Value='Target';
                               app.Lamp.Color='red';
                            end
                        end
                    end
                end
            end

        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            a=0;
            b=0;
            plot(app.UIAxes, a, b)
            app.xianshi.Value=' ';
            app.Lamp_2.Color='white';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'echo')
            xlabel(app.UIAxes, 't / ns')
            ylabel(app.UIAxes, 'Voltage / V')
            zlabel(app.UIAxes, 'Z')
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.withwithouttargetLabel = uilabel(app.UIFigure);
            app.withwithouttargetLabel.Text = 'with/without target';
            app.xianshi = uieditfield(app.UIFigure, 'text');
            app.CalculationButton = uibutton(app.UIFigure, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_true

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end